classdef myofibril_profiler_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyofibrilProfilerUIFigure      matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        LoadImageMenu                  matlab.ui.container.Menu
        ExportResultsMenu              matlab.ui.container.Menu
        LoadMeasurementMenu            matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        SummaryTable                   matlab.ui.control.Table
        Sarcomeres                     matlab.ui.control.UIAxes
        SarcomereMean                  matlab.ui.control.UIAxes
        ControlsPanel                  matlab.ui.container.Panel
        ProfilePanel                   matlab.ui.container.Panel
        ZLineStainCheckBox             matlab.ui.control.CheckBox
        CalibrationumpxEditField       matlab.ui.control.NumericEditField
        CalibrationumpxEditFieldLabel  matlab.ui.control.Label
        RelativePeakHeightEditField    matlab.ui.control.NumericEditField
        RelativePeakHeightEditFieldLabel  matlab.ui.control.Label
        PeakDistancepxEditField        matlab.ui.control.NumericEditField
        PeakDistancepxEditFieldLabel   matlab.ui.control.Label
        ProminenceEditField            matlab.ui.control.NumericEditField
        ProminenceEditFieldLabel       matlab.ui.control.Label
        ROIPanel                       matlab.ui.container.Panel
        ROIWidthpxEditField            matlab.ui.control.NumericEditField
        ROIWidthpxEditFieldLabel       matlab.ui.control.Label
        SelectPointsButton             matlab.ui.control.Button
        ChannelColormapPanel           matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        ProfilerPanel                  matlab.ui.container.Panel
        ProfileIntensityYCoord         matlab.ui.control.UIAxes
        ProfileIntensityXCoord         matlab.ui.control.UIAxes
        ProfileIntensity               matlab.ui.control.UIAxes
        ImageDisplayPanel              matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ImageTab                       matlab.ui.container.Tab
        ImageAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        myofibril_data = []
        spline_line
        shaded_stats
        ax_2 % Description
    end
    
    properties (Access = private)
        Tabs 
        ChannelAxes
        ChannelColors
    end

    methods (Access = public)

        function ExtractProfiles(app,channel_no)

            im = app.myofibril_data.image{channel_no};
            xs = app.myofibril_data.profile.xs;
            ys = app.myofibril_data.profile.ys;
            prominence = app.ProminenceEditField.Value;
            rel_peak_height = app.RelativePeakHeightEditField.Value;
            peak_distance = app.PeakDistancepxEditField.Value;
            calibration = app.CalibrationumpxEditField.Value;
            roi_width = app.ROIWidthpxEditField.Value;
            col = app.ChannelColors(channel_no,:);

            [prof_x, prof_y, im_profile] = improfile(im, xs, ys);
            figure(99)
            improfile(im, xs, ys);

            x_profile = 1:numel(im_profile);


            if roi_width > 1
                im_profile = quad_image_rotation;
                x_profile(end) = [];
                prof_x(end) = [];
                prof_y(end) = [];

                v = [rot_prof_x(1,1) rot_prof_y(1,1);rot_prof_x(end,1) rot_prof_y(end,1);...
                    rot_prof_x(1,2) rot_prof_y(1,2);rot_prof_x(end,2) rot_prof_y(end,2)];
                f = [1 2 4 3 1];
                patch(app.ChannelAxes{channel_no},'Vertices',v,'Faces',f,'FaceAlpha',0.5, ...
                    'EdgeColor',[1 1 1],'EdgeAlpha',0.25, ...
                    'LineWidth',2)

            end
            
            [pks_z_line, locs_z_line] = findpeaks(-rescale(im_profile), ...
            'MinPeakProminence', prominence * range(rescale(im_profile)), ...
            'MinPeakDistance',peak_distance);

            figure(999)
            findpeaks(-rescale(im_profile), ...
            'MinPeakProminence', prominence * range(rescale(im_profile)), ...
            'MinPeakDistance',25);
            for i = 1 : numel(locs_z_line)
                plot(app.ProfileIntensityXCoord,xs(locs_z_line((i)))*ones(1,10),linspace(0,1,10),'LineStyle','--','color',col,'LineWidth',1.7)
                plot(app.ProfileIntensityYCoord,ys(locs_z_line((i)))*ones(1,10),linspace(0,1,10),'LineStyle','--','color',col,'LineWidth',1.7)

            end

            no_of_sarcomeres = numel(locs_z_line) - 1;

            for i = 1 : no_of_sarcomeres
                distance = arclength(prof_x(locs_z_line(i):locs_z_line(i+1)),prof_y(locs_z_line(i):locs_z_line(i+1)),'spline');
                sarc_len(i) = calibration*distance;

            end


            sarc_col = return_color_scheme(col,no_of_sarcomeres);

            hold(app.ProfileIntensity,'on')
            hold(app.ProfileIntensityXCoord,'on')
            hold(app.ProfileIntensityYCoord,'on')

            plot3(app.ProfileIntensity,prof_x,prof_y,rescale(im_profile),'color',col,'LineWidth',1.7);
            ang = atan2((prof_y(end) - prof_y(1)), (prof_x(end) - prof_x(1)));
            view(app.ProfileIntensity,[rad2deg(ang),30])
            plot(app.ProfileIntensityXCoord,prof_x,rescale(im_profile),'Color',col,'LineWidth',1.7);
            plot(app.ProfileIntensityYCoord,prof_y,rescale(im_profile),'Color',col,'LineWidth',1.7);


            for i = 1 : no_of_sarcomeres

                mean_sarc_len = mean(sarc_len);
                x_sarc_profile(i,:) = linspace(0,mean_sarc_len, 1000);
                profile_indices = locs_z_line(i) : locs_z_line(i+1);
                x_temp = mean_sarc_len*normalize(profile_indices, 'range');
                y_sarc_profile(i, :) = interp1(x_temp, im_profile(profile_indices), ...
                    x_sarc_profile(i,:));
                y_sarc_profile(i,:) = normalize(y_sarc_profile(i,:),'range');
               
                locs_all = [];
                [~, locs_all] = findpeaks(y_sarc_profile(i,:), ...
                    'MinPeakProminence', 0.15*prominence * range(y_sarc_profile(i,:)))
                figure(9)
                findpeaks(y_sarc_profile(i,:), ...
                    'MinPeakProminence', 0.15*prominence * range(y_sarc_profile(i,:)))
                if numel(locs_all) > 2
                    locs_all(2:end-1) = [];
                end

                locs_m_line = [];
                try
                    [~,locs_m_line] = max(-y_sarc_profile(i,locs_all(1):locs_all(2)))
                catch
                    utku = 9
                end
                locs_m_line = locs_m_line + locs_all(1) - 1;

                x_sarc_profile(i,:) = x_sarc_profile(i,:)  - x_sarc_profile(i,locs_m_line);

                fwhm_ix(i,1) = find(y_sarc_profile(i,:) >= 0.5*y_sarc_profile(i,locs_all(1)),1,'first');
                fwhm_ix(i,2) = find(y_sarc_profile(i,:) >= 0.5*y_sarc_profile(i,locs_all(2)),1,'last');
                fwhm(i) = x_sarc_profile(i,fwhm_ix(i,2)) - x_sarc_profile(i,fwhm_ix(i,1));

                hold(app.Sarcomeres,'on')

                plot(app.Sarcomeres,x_sarc_profile(i,:),y_sarc_profile(i,:),'Color',sarc_col(i,:),'LineWidth',1.7)
                scatter(app.Sarcomeres,x_sarc_profile(i,locs_m_line),y_sarc_profile(i,locs_m_line),80,'d','MarkerEdgeColor',[0 0 0],...
                    'MarkerFaceColor',[1 0 1],...
                    'MarkerFaceAlpha',0.5)
                scatter(app.Sarcomeres,x_sarc_profile(i,locs_all),y_sarc_profile(i,locs_all),80,'d','MarkerEdgeColor',[0 0 0],...
                    'MarkerFaceColor',[1 0 0],...
                    'MarkerFaceAlpha',0.5)

            end
            % fwhm_ix
            mean_sarc_profile = mean(y_sarc_profile);
            mean_fwhm = mean(fwhm);

            x_sarc_profile_mean = mean(x_sarc_profile,1)
            hold(app.SarcomereMean,"on")
            if size(y_sarc_profile,1) == 1
                plot(app.SarcomereMean, x_sarc_profile_mean,y_sarc_profile,'-','LineWidth',1.7,'Color',col)
            else
                shadedErrorBar2(x_sarc_profile_mean, y_sarc_profile, {@mean, @std},{'-','LineWidth',1.7,'Color',col},0,app.SarcomereMean);
            end

            % plot(app.SarcomereMean,x_sarc_profile(fwhm_ix(1):fwhm_ix(2)),linspace(mean_sarc_profile(fwhm_ix(1)),...
            %     mean_sarc_profile(fwhm_ix(2)),numel(fwhm_ix(1):fwhm_ix(2))),':m')
            xlim(app.Sarcomeres,[-0.51*mean_sarc_len 0.51*mean_sarc_len])
            xlim(app.SarcomereMean,[-0.51*mean_sarc_len 0.51*mean_sarc_len])

            app.myofibril_data.profile(channel_no).intensity = im_profile;
            app.myofibril_data.profile(channel_no).sarcomere_intensities = y_sarc_profile;
            app.myofibril_data.profile(channel_no).sarcomere_location = x_sarc_profile;
            app.myofibril_data.profile(channel_no).mean_sarcomere_profile = mean_sarc_profile;
            app.myofibril_data.profile(channel_no).std_sarcomere_profile = std(y_sarc_profile);
            app.myofibril_data.profile(channel_no).sem_sarcomere_profile = std(y_sarc_profile)/sqrt(length(y_sarc_profile));
            app.myofibril_data.profile(channel_no).sarcomere_lengths = sarc_len;
            app.myofibril_data.profile(channel_no).fwhm = fwhm;
            app.myofibril_data.calibration = calibration;
                
            
            for i = 1 : no_of_sarcomeres
                st.channel(i,:) = channel_no;
                st.band_no(i,:) = i;
                st.color{i,:} = '';
            end
            st.sarcomere_lengths = app.myofibril_data.profile(channel_no).sarcomere_lengths';
            st.fwhm = app.myofibril_data.profile(channel_no).fwhm';

            
            app.SummaryTable.Data = [app.SummaryTable.Data; struct2table(st)];
            
            if channel_no > 1
                starting_ix = size(app.SummaryTable.Data,1) - no_of_sarcomeres;
            else
                starting_ix = 0;
            end

            for i = 1 : no_of_sarcomeres
                s = uistyle("BackgroundColor",sarc_col(i,:));
                addStyle(app.SummaryTable,s,"cell",[starting_ix+i 3])
            end

        end

        function RefreshDisplay(app)

            ax = {'ProfileIntensity','Sarcomeres','SarcomereMean','ProfileIntensityXCoord','ProfileIntensityYCoord'};

            for i = 1:numel(ax)
                cla(app.(ax{i}))
            end

            app.SummaryTable.Data = [];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('utilities'))
            movegui(app.MyofibrilProfilerUIFigure,'center')
            colormap(app.ImageAxes, 'gray');
            
        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file_string,path_string]=uigetfile2( ...
                {'*.nd2','ND2';'*.png','PNG'}, ...
                'Select Image File');
            delete(f)

            if (path_string~=0)
                app.myofibril_data = [];
                app.myofibril_data.image_file_string = fullfile(path_string,file_string);
                im_ax = app.ImageAxes;
                app.Tabs = [];

                
                if contains(file_string,'.nd2')
                    app.myofibril_data.image_file = bfopen(app.myofibril_data.image_file_string);
                    app.myofibril_data.meta_file = app.myofibril_data.image_file{1,2};
                    h = app.myofibril_data.meta_file;
                    app.CalibrationumpxEditField.Value = h.get('Global dCalibration');
                    im = app.myofibril_data.image_file;
                    im = im{1,1};
                    im(:,2) = [];
                    app.myofibril_data.image = im;
                    sz = size(im);
                    delete(app.TabGroup.Children)
                    for i = 1:sz(1) 
                        app.Tabs{i} = uitab(app.TabGroup,'Title',['Channel ' num2str(i)]);
                        app.ChannelAxes{i} = copyobj(im_ax,app.Tabs{i});
                        app.ChannelAxes{i}.Visible = 'on';
                        center_image_with_preserved_aspect_ratio(app.myofibril_data.image{i,1},app.ChannelAxes{i})
                        
                        w_text = sprintf('Global CSU-W1, FilterChanger(EM) #%i',i);

                        w_text = h.get(w_text);

                        em_wavelength = str2double(extractBetween(w_text,"(","/"))

                        app.ChannelColors(i,:) = wavelength2color(em_wavelength)

                    end
                    app.Tabs{end+1} = uitab(app.TabGroup,'Title','Merged ');
                    app.ChannelAxes{end+1} = copyobj(im_ax,app.Tabs{end});
                    app.ChannelAxes{end}.Visible = 'on';
                    app.RefreshDisplay
                else
                    im = imread(app.myofibril_data.image_file_string);
                    app.myofibril_data.image = im;  
                    app.ChannelAxes{1} = copyobj(im_ax,app.ImageTab);
                    app.ChannelAxes{1}.Visible = 'on';
                end
                
                % center_image_with_preserved_aspect_ratio(app.myofibril_data.image,app.ImageAxes)

            end
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
            
            selected_tab = app.TabGroup.SelectedTab.Title;
            try
                image_axis_index = str2double(regexp(selected_tab,'\d*','Match'));
                im_axis = app.ChannelAxes{image_axis_index};
                tab_number_excluding_merged = (numel(app.TabGroup.Children) - 1);

                tabs_to_copy = 1 : tab_number_excluding_merged;

            catch
                return
            end

            if  isfield(app.myofibril_data,'profile')
                app.myofibril_data.profile = [];

                for i = 1 : numel(tabs_to_copy)
                    cla(app.ChannelAxes{i})
                    center_image_with_preserved_aspect_ratio(app.myofibril_data.image{i,1},app.ChannelAxes{i})
                end
                app.RefreshDisplay
            end

            tabs_to_copy(image_axis_index) = [];


            app.myofibril_data.profile.roi{image_axis_index} = drawpolyline(im_axis,'LineWidth',1E-32,'MarkerSize',10);


            x = app.myofibril_data.profile.roi{image_axis_index}.Position(:,1);
            y = app.myofibril_data.profile.roi{image_axis_index}.Position(:,2);
            cs = spline(x, y);
            app.myofibril_data.profile.xs = linspace(x(1), x(end), 1000);
            app.myofibril_data.profile.ys = ppval(cs, app.myofibril_data.profile.xs);

            hold(im_axis,'on')
            app.spline_line(image_axis_index) = plot(im_axis,app.myofibril_data.profile.xs,app.myofibril_data.profile.ys,"Color",'m','LineWidth',2);
            

            addlistener(app.myofibril_data.profile.roi{image_axis_index},"ROIMoved",@(src,evt) update_profile(evt));

            for i = 1 : numel(tabs_to_copy)
                app.myofibril_data.profile.roi{i} = copyobj(app.myofibril_data.profile.roi{image_axis_index}, app.ChannelAxes{tabs_to_copy(i)})
                addlistener(app.myofibril_data.profile.roi{i},"ROIMoved",@(src,evt) update_profile(evt));
                app.spline_line(i) = copyobj(app.spline_line(image_axis_index), app.ChannelAxes{tabs_to_copy(i)});

            end
            
            for channel_no = 1 : tab_number_excluding_merged
                app.ExtractProfiles(channel_no)
            end


            function update_profile(evt)
                call_back_tab = app.TabGroup.SelectedTab.Title;
                call_back_tab_no = str2double(regexp(call_back_tab,'\d*','Match'));
                x_upt = app.myofibril_data.profile.roi{call_back_tab_no}.Position(:,1);
                y_upt = app.myofibril_data.profile.roi{call_back_tab_no}.Position(:,2);
                cs_upt = spline(x_upt, y_upt);
                xs_upt = linspace(x_upt(1), x_upt(end), 1000);
                ys_upt = ppval(cs_upt, xs_upt);
                app.myofibril_data.profile.xs = linspace(x_upt(1), x_upt(end), 1000);
                app.myofibril_data.profile.ys = ppval(cs_upt, app.myofibril_data.profile.xs);

                for spline_count = 1 : size(app.spline_line,2)
                    app.spline_line(spline_count).XData = app.myofibril_data.profile.xs;
                    app.spline_line(spline_count).YData = app.myofibril_data.profile.ys;
                    app.myofibril_data.profile.roi{spline_count}.Position(:,1) = x_upt;
                    app.myofibril_data.profile.roi{spline_count}.Position(:,2) = y_upt;
                end

                app.RefreshDisplay;
                return
                app.ExtractProfiles;
                app.ImageAxes
            end

        end

        % Value changed function: ProminenceEditField
        function ProminenceEditFieldValueChanged(app, event)
            app.RefreshDisplay;
            for channel_no = 1 : size(app.Tabs,2)-1
                app.ExtractProfiles(channel_no)
            end
        end

        % Value changed function: CalibrationumpxEditField
        function CalibrationumpxEditFieldValueChanged(app, event)
            app.RefreshDisplay;
            for channel_no = 2 : size(app.Tabs,2)-1
                app.ExtractProfiles(channel_no)
            end
        end

        % Menu selected function: ExportResultsMenu
        function ExportResultsMenuSelected(app, event)
            [file_string,path_string] = uiputfile2( ...
                {'*.xlsx','Excel file'},'Enter Excel File Name For Analysis Results');

            if (path_string~=0)
                output_file_string = fullfile(path_string,file_string);

                sum_out.image_file = app.myofibril_data.image_file_string;
                sum_out.analyzed_image_number = app.ChannelSpinner.Value;
                sum_out.calibration_px_to_um = app.myofibril_data.calibration;

                for i = 1 : numel(app.myofibril_data.profile.sarcomere_lengths)

                    var = sprintf('sarcomere_length_%i_um',i);
                    sum_out.(var) = app.myofibril_data.profile.sarcomere_lengths(i)

                end

                sum_out.mean_sl_um = mean(app.myofibril_data.profile.sarcomere_lengths);
                sum_out.std_sl_um = std(app.myofibril_data.profile.sarcomere_lengths);
                sum_out.sem_sl_um = std(app.myofibril_data.profile.sarcomere_lengths)/...
                    sqrt(numel(app.myofibril_data.profile.sarcomere_lengths));
                sum_out.fwhm_um = app.myofibril_data.profile.fwhm;

                sarcomere.location_um = app.myofibril_data.profile.sarcomere_location';

                for i = 1 : numel(app.myofibril_data.profile.sarcomere_lengths)
                    var = sprintf('sarcomere_%i_intensity',i);
                    sarcomere.(var) = app.myofibril_data.profile.sarcomere_intensities(i,:)';
                end

                sum_profiles.location_um = sarcomere.location_um;
                vars = {'mean_sarcomere_profile','std_sarcomere_profile','sem_sarcomere_profile'};
                for i = 1 : numel(vars)
                    sum_profiles.(vars{i}) = app.myofibril_data.profile.(vars{i})';
                end

                try
                    delete(output_file_string)
                end

                writetable(struct2table(sum_out),output_file_string,'Sheet','Summary')
                writetable(struct2table(sarcomere),output_file_string,'Sheet','Sarcomere Profiles')
                writetable(struct2table(sum_profiles),output_file_string,'Sheet','Summary Profiles')

                output_file_string = replace(output_file_string,'.xlsx','.myoprof');
                meta_out = app.myofibril_data;
                save(output_file_string,'meta_out')


            end
        end

        % Value changed function: ROIWidthpxEditField
        function ROIWidthpxEditFieldValueChanged(app, event)
            if  isfield(app.myofibril_data,'profile')
                app.RefreshDisplay;
            for channel_no = 1 : size(app.Tabs,2)
                app.ExtractProfiles(channel_no)
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyofibrilProfilerUIFigure = uifigure('Visible', 'off');
            app.MyofibrilProfilerUIFigure.Name = 'MyofibrilProfiler';
            app.Menu = uimenu(app.MyofibrilProfilerUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadImageMenu = uimenu(app.Menu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.ExportResultsMenu = uimenu(app.Menu);
            app.ExportResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResultsMenuSelected, true);
            app.ExportResultsMenu.Text = 'Export Results';
            app.LoadMeasurementMenu = uimenu(app.Menu);
            app.LoadMeasurementMenu.Visible = 'off';
            app.LoadMeasurementMenu.Text = 'Load Measurement';
            app.ImageDisplayPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.ImageAxes = uiaxes(app.ImageDisplayPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.TabGroup = uitabgroup(app.ImageDisplayPanel);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ProfilerPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ProfilerPanel.Title = 'Profiler Panel';
            app.ProfileIntensity = uiaxes(app.ProfilerPanel);
            title(app.ProfileIntensity, 'Extracted Profile')
            xlabel(app.ProfileIntensity, 'Horizontal Location (px)')
            ylabel(app.ProfileIntensity, 'Vertical Location (px)')
            zlabel(app.ProfileIntensity, {'Normalized Intensity'; 'Over Full Range'})
            app.ProfileIntensityXCoord = uiaxes(app.ProfilerPanel);
            title(app.ProfileIntensityXCoord, 'Profile Intensity')
            xlabel(app.ProfileIntensityXCoord, 'Horizontal Location (px)')
            ylabel(app.ProfileIntensityXCoord, {'Normalized Intensity'; 'Over Full Range'})
            zlabel(app.ProfileIntensityXCoord, 'Z')
            app.ProfileIntensityYCoord = uiaxes(app.ProfilerPanel);
            title(app.ProfileIntensityYCoord, 'Profile Intensity')
            xlabel(app.ProfileIntensityYCoord, 'Vertical Location (px)')
            ylabel(app.ProfileIntensityYCoord, {'Normalized Intensity'; 'Over Full Range'})
            zlabel(app.ProfileIntensityYCoord, 'Z')
            app.ControlsPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.ChannelColormapPanel = uipanel(app.ControlsPanel);
            app.ChannelColormapPanel.Title = 'Channel Colormap';
            app.DropDown = uidropdown(app.ChannelColormapPanel);
            app.DropDown.Items = {'Em. Wavelength', 'Parula'};
            app.DropDown.Value = 'Em. Wavelength';
            app.ROIPanel = uipanel(app.ControlsPanel);
            app.ROIPanel.Title = 'ROI';
            app.SelectPointsButton = uibutton(app.ROIPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.ROIWidthpxEditFieldLabel = uilabel(app.ROIPanel);
            app.ROIWidthpxEditFieldLabel.Text = 'ROI Width (px)';
            app.ROIWidthpxEditField = uieditfield(app.ROIPanel, 'numeric');
            app.ROIWidthpxEditField.ValueChangedFcn = createCallbackFcn(app, @ROIWidthpxEditFieldValueChanged, true);
            app.ROIWidthpxEditField.Value = 1;
            app.ProfilePanel = uipanel(app.ControlsPanel);
            app.ProfilePanel.Title = 'Profile';
            app.ProminenceEditFieldLabel = uilabel(app.ProfilePanel);
            app.ProminenceEditFieldLabel.Text = 'Prominence ';
            app.ProminenceEditField = uieditfield(app.ProfilePanel, 'numeric');
            app.ProminenceEditField.Limits = [0 1];
            app.ProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @ProminenceEditFieldValueChanged, true);
            app.ProminenceEditField.Value = 0.5;
            app.PeakDistancepxEditFieldLabel = uilabel(app.ProfilePanel);
            app.PeakDistancepxEditFieldLabel.Text = 'Peak Distance (px)';
            app.PeakDistancepxEditField = uieditfield(app.ProfilePanel, 'numeric');
            app.PeakDistancepxEditField.Limits = [0 Inf];
            app.PeakDistancepxEditField.Value = 25;
            app.RelativePeakHeightEditFieldLabel = uilabel(app.ProfilePanel);
            app.RelativePeakHeightEditFieldLabel.Text = 'Relative Peak Height';
            app.RelativePeakHeightEditField = uieditfield(app.ProfilePanel, 'numeric');
            app.RelativePeakHeightEditField.Limits = [0 Inf];
            app.RelativePeakHeightEditField.Value = 0.8;
            app.CalibrationumpxEditFieldLabel = uilabel(app.ProfilePanel);
            app.CalibrationumpxEditFieldLabel.Text = 'Calibration (um/px)';
            app.CalibrationumpxEditField = uieditfield(app.ProfilePanel, 'numeric');
            app.CalibrationumpxEditField.Limits = [0 Inf];
            app.CalibrationumpxEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationumpxEditFieldValueChanged, true);
            app.CalibrationumpxEditField.Value = 1;
            app.ZLineStainCheckBox = uicheckbox(app.ProfilePanel);
            app.ZLineStainCheckBox.Enable = 'off';
            app.ZLineStainCheckBox.Visible = 'off';
            app.ZLineStainCheckBox.Text = 'Z-Line Stain';
            app.Panel = uipanel(app.MyofibrilProfilerUIFigure);
            app.Panel.Title = 'Panel';
            app.SarcomereMean = uiaxes(app.Panel);
            title(app.SarcomereMean, 'Average Sarcomere Intensity')
            xlabel(app.SarcomereMean, 'Location (um)')
            ylabel(app.SarcomereMean, {'Normalized Intensity'; 'Over Sarcomere'})
            app.Sarcomeres = uiaxes(app.Panel);
            title(app.Sarcomeres, 'Sarcomere Intensities')
            xlabel(app.Sarcomeres, 'Location (um)')
            ylabel(app.Sarcomeres, {'Normalized Intensity'; 'Over Sarcomere'})
            app.SummaryTable = uitable(app.Panel);
            app.SummaryTable.ColumnName = {'Channel'; 'Profile Number'; 'Color'; 'SL (um)'; 'FWHM (um)'};
            app.SummaryTable.RowName = {};
            % Show the figure after all components are created
            app.MyofibrilProfilerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myofibril_profiler_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyofibrilProfilerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyofibrilProfilerUIFigure)
        end
    end
end